classdef run < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DropDown                matlab.ui.control.DropDown
        DropDownLabel           matlab.ui.control.Label
        Robot2Label             matlab.ui.control.Label
        Robot1Label             matlab.ui.control.Label
        GoalYSpinner_2          matlab.ui.control.Spinner
        GoalYSpinner_2Label     matlab.ui.control.Label
        GoalXSpinner_2          matlab.ui.control.Spinner
        GoalXSpinner_2Label     matlab.ui.control.Label
        InitialYSpinner_2       matlab.ui.control.Spinner
        InitialYSpinner_2Label  matlab.ui.control.Label
        InitialXSpinner_2       matlab.ui.control.Spinner
        InitialXSpinner_2Label  matlab.ui.control.Label
        EnterinitialandgoalpositionfortherobotLabel  matlab.ui.control.Label
        ContinueButton          matlab.ui.control.Button
        PauseButton             matlab.ui.control.Button
        StopButton              matlab.ui.control.Button
        GoalYSpinner            matlab.ui.control.Spinner
        GoalYSpinnerLabel       matlab.ui.control.Label
        GoalXSpinner            matlab.ui.control.Spinner
        GoalXSpinnerLabel       matlab.ui.control.Label
        InitialYSpinner         matlab.ui.control.Spinner
        InitialYSpinnerLabel    matlab.ui.control.Label
        InitialXSpinner         matlab.ui.control.Spinner
        InitialXSpinnerLabel    matlab.ui.control.Label
        StartButton             matlab.ui.control.Button
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes4                 matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            selectedAlgorithm = app.DropDown.Value;
              %% --- Visualization Setup ---
            figAnim = figure('Name','UGV Animation','NumberTitle','off');
            axAnim = axes(figAnim);
            
            %% --- Inputs from UI ---
            x1_initial = app.InitialXSpinner.Value;
            y1_initial = app.InitialYSpinner.Value;
            x1_goal = app.GoalXSpinner.Value;
            y1_goal = app.GoalYSpinner.Value;
            
            x2_initial = app.InitialXSpinner_2.Value;
            y2_initial = app.InitialYSpinner_2.Value;
            x2_goal    = app.GoalXSpinner_2.Value;
            y2_goal    = app.GoalYSpinner_2.Value;
            
            global stop_button pause_button continue_button;
            stop_button = 0; pause_button = 0; continue_button = 0;
            
            %% --- State Vectors: [x, y, yaw, v, omega]' ---
            
            % Calculate initial angles so they face the goal immediately
            theta1_start = atan2(y1_goal - y1_initial, x1_goal - x1_initial);
            theta2_start = atan2(y2_goal - y2_initial, x2_goal - x2_initial);

            x1 = [x1_initial y1_initial theta1_start 0 0]'; % Robot 1
            x2 = [x2_initial y2_initial theta2_start 0 0]'; % Robot 2
            
            goal1 = [x1_goal, y1_goal];
            goal2 = [x2_goal, y2_goal];
            
            % Obstacles List
            obstacle = [0 2; 2 2; 2 4; 2 6; 4 2; 4 4; 5 4; 6 0; 
                        5 9; 8 2; 8 4; 8 6; 8 8; 10 4];
            obstacleR = 0.3; % Radius for collision detection
            
            global dt; dt = 0.1; % Time step [s]
            
            % Kinematic limits: [v_max, w_max, a_max, alpha_max, dv, dw]
            Kinematic = [2.0, deg2rad(40), 0.4, deg2rad(80), 0.03, deg2rad(3)];
            % Evaluation params: [heading, dist, velocity, predictDT]
            % OLD: evalParam = [0.1, 0.3, 0.1, 1.5];
            % Increased dist weight (0.4) and predictDT (3.0) for earlier reaction
            evalParam = [0.4, 0.1, 0.3, 3.0];
            area = [-1 12 -1 12]; % Map limits [xmin xmax ymin ymax]
            ArrowLength = 0.5; 
            
            % Simulation Result Containers
            result1.x = [];  % Robot 1 trajectory history
            result2.x = [];  % Robot 2 trajectory history
            goal1_reached = false;
            goal2_reached = false;
            
            tic; % Start timer
            
            %% --- PRE-CALCULATION (OFFLINE PLANNERS) ---
            path1 = []; path2 = [];
            idx1 = 2; idx2 = 2; % Waypoint tracking indices
            
            if strcmp(selectedAlgorithm, 'A*')
                % Calculate A* Path
                grid_res = 0.25;
                path1 = A_Star_Planner([x1_initial, y1_initial], goal1, obstacle, area, grid_res);
                path2 = A_Star_Planner([x2_initial, y2_initial], goal2, obstacle, area, grid_res);
                
            elseif strcmp(selectedAlgorithm, 'RRT*')
                % Calculate RRT* Path
                disp('Calculating RRT* Paths...');
                path1 = RRT_Star_Planner([x1_initial, y1_initial], goal1, obstacle, area);
                path2 = RRT_Star_Planner([x2_initial, y2_initial], goal2, obstacle, area);
            end
            
            %% --- MAIN SIMULATION LOOP ---
            for i = 1:5000
                if stop_button == 1; break; end
                
                % Reset trajectory vars to prevent plot errors
                traj1 = []; traj2 = []; 
                
                switch selectedAlgorithm
                    
                    case 'DWA' 
                        % --- DWA LOGIC (SMOOTH "EARLY TURN" TUNING) ---
                        
                        % Global Prediction Parameters
                        pred_time = 2.0; 
                        pred_steps = 15;
                        dt_p = pred_time / pred_steps;
                        
                        % --- ROBOT 1 PLANNING ---
                        if ~goal1_reached
                            % 1. Predict Robot 2
                            pred_obs2 = [];
                            t_x = x2(1); t_y = x2(2); t_theta = x2(3);
                            t_v = x2(4); t_w = x2(5);
                            for k = 1:pred_steps
                                t_theta = t_theta + t_w * dt_p;
                                t_x = t_x + t_v * cos(t_theta) * dt_p;
                                t_y = t_y + t_v * sin(t_theta) * dt_p;
                                pred_obs2 = [pred_obs2; t_x, t_y];
                            end
                            ob1 = [obstacle; pred_obs2];
                            
                            % --- SMART TUNING ROBOT 1 ---
                            dist_to_goal = norm(x1(1:2)' - goal1);
                            min_dist_obs = 100;
                            for k=1:size(ob1,1)
                                d = norm(ob1(k,:) - x1(1:2)') - obstacleR;
                                if d < min_dist_obs; min_dist_obs = d; end
                            end
                            
                            % 1. DEFAULT MODE (Open Space)
                            % [Heading, Dist, Vel, PredictDT]
                            current_eval1 = [0.7, 0.62, 0.94, 1.3]; 
                            
                            if dist_to_goal < 1.5
                                % 2. PARKING MODE (Near Goal)
                                % Look at goal (0.6), move slow (0.2)
                                current_eval1 = [0.68, 0.78, 0.82, 1.1];
                                
                            elseif min_dist_obs < 0.8
                                % 4. CRITICAL AVOIDANCE (Panic/Close)
                                % Drop prediction to 1.0s, allow any heading
                                current_eval1 = [0.10, 0.78, 0.87, 2.2];
                                
                            elseif min_dist_obs < 2.0
                                % 3. CAUTION MODE (Early Warning) <--- NEW!
                                % Start turning NOW, don't wait for 0.8m.
                                % Lower Heading (0.2) allows the curve.
                                current_eval1 = [0.14, 0.11, 0.44, 3.0];
                            end
                            
                            [u1, traj1] = DynamicWindowApproach(x1, Kinematic, goal1, current_eval1, ob1, obstacleR);
                        else
                            u1 = [0; 0]; traj1 = [];
                        end
                        
                        % --- ROBOT 2 PLANNING ---
                        if ~goal2_reached
                            % 2. Predict Robot 1
                            pred_obs1 = [];
                            t_x = x1(1); t_y = x1(2); t_theta = x1(3);
                            t_v = x1(4); t_w = x1(5);
                            for k = 1:pred_steps
                                t_theta = t_theta + t_w * dt_p;
                                t_x = t_x + t_v * cos(t_theta) * dt_p;
                                t_y = t_y + t_v * sin(t_theta) * dt_p;
                                pred_obs1 = [pred_obs1; t_x, t_y];
                            end
                            ob2 = [obstacle; pred_obs1];
                            
                            % --- SMART TUNING ROBOT 2 ---
                            dist_to_goal = norm(x2(1:2)' - goal2);
                            min_dist_obs = 100;
                            for k=1:size(ob2,1)
                                d = norm(ob2(k,:) - x2(1:2)') - obstacleR;
                                if d < min_dist_obs; min_dist_obs = d; end
                            end
                            
                            current_eval2 = [0.7, 0.62, 0.94, 1.3]; 
                            
                            if dist_to_goal < 1.5
                                current_eval2 = [0.68, 0.78, 0.82, 1.1];
                            elseif min_dist_obs < 0.8
                                current_eval2 = [0.10, 0.78, 0.87, 2.2];
                            elseif min_dist_obs < 2.0
                                % CAUTION MODE
                                current_eval2 = [0.14, 0.11, 0.44, 3.0];
                            end
                            
                            [u2, traj2] = DynamicWindowApproach(x2, Kinematic, goal2, current_eval2, ob2, obstacleR);
                        else
                            u2 = [0; 0]; traj2 = [];
                        end

                    case {'A*', 'RRT*'}
                        % --- PATH FOLLOWING LOGIC (OFFLINE PLANNERS) ---
                        
                        % ROBOT 1 CONTROL
                        if ~goal1_reached
                             if norm(x1(1:2)' - goal1) < 0.5
                                 goal1_reached = true; u1 = [0;0];
                             else
                                 % Waypoint Management
                                 if idx1 > size(path1, 1); idx1 = size(path1, 1); end
                                 target = path1(idx1, :);
                                 
                                 % Switch to next waypoint if close (0.4m)
                                 if norm(x1(1:2)' - target) < 0.4 && idx1 < size(path1, 1)
                                     idx1 = idx1 + 1; 
                                 end
                                 
                                 % Pure Pursuit / Heading Controller
                                 desired = atan2(target(2)-x1(2), target(1)-x1(1));
                                 err = atan2(sin(desired-x1(3)), cos(desired-x1(3)));
                                 
                                 % Speed Control (Slow down on turns)
                                 v = 1.0; 
                                 if abs(err) > 0.3; v = 0.1; end 
                                 u1 = [v; 3.0 * err]; 
                             end
                        else; u1=[0;0]; end
                        
                        % ROBOT 2 CONTROL
                        if ~goal2_reached
                             if norm(x2(1:2)' - goal2) < 0.5
                                 goal2_reached = true; u2 = [0;0];
                             else
                                 if idx2 > size(path2, 1); idx2 = size(path2, 1); end
                                 target = path2(idx2, :);
                                 
                                 if norm(x2(1:2)' - target) < 0.4 && idx2 < size(path2, 1)
                                     idx2 = idx2 + 1; 
                                 end
                                 
                                 desired = atan2(target(2)-x2(2), target(1)-x2(1));
                                 err = atan2(sin(desired-x2(3)), cos(desired-x2(3)));
                                 
                                 v = 1.0; 
                                 if abs(err) > 0.3; v = 0.1; end
                                 
                                 % Collision Avoidance: Robot 2 waits for Robot 1
                                 if norm(x1(1:2)-x2(1:2)) < 1.0; v = 0; end
                                 
                                 u2 = [v; 3.0 * err];
                             end
                        else; u2=[0;0]; end
                end
                
                %% --- Apply Motion Model ---
                x1 = f(x1, u1);
                x2 = f(x2, u2);
                
                % Store History
                result1.x = [result1.x; x1'];
                result2.x = [result2.x; x2'];
                
                %% --- Global Goal Checks ---
                if strcmp(selectedAlgorithm, 'DWA')
                    if ~goal1_reached && norm(x1(1:2) - goal1') < 0.5; goal1_reached = true; end
                    if ~goal2_reached && norm(x2(1:2) - goal2') < 0.5; goal2_reached = true; end
                end
                
                if goal1_reached && goal2_reached; break; end
                
                % Safety Bounds Check
                if x1(1)>12 || x1(1)<-1 || x1(2)>12 || x1(2)<-1; break; end
                
                %% --- Animation ---
                axes(axAnim); cla(axAnim); hold(axAnim,'on');
                
                % Draw Robot 1
                quiver(axAnim, x1(1), x1(2), ArrowLength*cos(x1(3)), ArrowLength*sin(x1(3)), 'sk');
                if ~isempty(result1.x)
                    plot(axAnim, result1.x(:,1), result1.x(:,2), '-b'); 
                end
                plot(axAnim, goal1(1), goal1(2), 'ob');
                
                % Draw Robot 2
                quiver(axAnim, x2(1), x2(2), ArrowLength*cos(x2(3)), ArrowLength*sin(x2(3)), 'sr');
                if ~isempty(result2.x)
                    plot(axAnim, result2.x(:,1), result2.x(:,2), '-r'); 
                end
                plot(axAnim, goal2(1), goal2(2), 'om');
                
                % Draw Obstacles
                plot(axAnim, obstacle(:,1), obstacle(:,2), 'ks', 'MarkerSize', 8, 'MarkerFaceColor','k');
                
                % Draw Paths (Only for A* and RRT*)
                if (strcmp(selectedAlgorithm, 'A*') || strcmp(selectedAlgorithm, 'RRT*'))
                    if ~isempty(path1); plot(axAnim, path1(:,1), path1(:,2), '--b', 'LineWidth', 1.5); end
                    if ~isempty(path2); plot(axAnim, path2(:,1), path2(:,2), '--r', 'LineWidth', 1.5); end
                end
                
                % Draw DWA Prediction Trajectories (Only for DWA)
                if strcmp(selectedAlgorithm, 'DWA')
                    if ~isempty(traj1)
                        for it = 1:length(traj1(:,1))/5
                            ind = 1 + (it-1)*5;
                            plot(axAnim, traj1(ind,:), traj1(ind+1,:), 'Color', [0.6 0.6 1]); 
                        end
                    end
                    if ~isempty(traj2)
                        for it = 1:length(traj2(:,1))/5
                            ind = 1 + (it-1)*5;
                            plot(axAnim, traj2(ind,:), traj2(ind+1,:), 'Color', [1 0.6 0.6]); 
                        end
                    end
                end
                
                axis(axAnim, area); grid(axAnim,'on'); hold(axAnim,'off');
                drawnow limitrate;
            end 
            
            %% --- Post-Processing Plots ---
            nSteps = size(result1.x, 1);
            t = (0:nSteps-1)' * dt;
            
            % 1. Velocity Plot
            cla(app.UIAxes2); hold(app.UIAxes2, 'on');
            plot(app.UIAxes2, t, result1.x(:,4), '-b');
            if size(result2.x,1) == nSteps; plot(app.UIAxes2, t, result2.x(:,4), '-r'); end
            title(app.UIAxes2, 'Velocity of the Robots');
            xlabel(app.UIAxes2, 'Time [s]'); ylabel(app.UIAxes2, 'Velocity [m/s]');
            legend(app.UIAxes2, {'Robot 1','Robot 2'}); hold(app.UIAxes2, 'off');
            
            % 2. Angular Velocity Plot
            cla(app.UIAxes3); hold(app.UIAxes3, 'on');
            plot(app.UIAxes3, t, result1.x(:,5), '-b');
            if size(result2.x,1) == nSteps; plot(app.UIAxes3, t, result2.x(:,5), '-r'); end
            title(app.UIAxes3, 'Angular velocity of the robots');
            xlabel(app.UIAxes3, 'Time [s]'); ylabel(app.UIAxes3, 'Angular Velocity [rad/s]');
            legend(app.UIAxes3, {'Robot 1','Robot 2'}); hold(app.UIAxes3, 'off');
            
            % 3. Orientation Plot
            cla(app.UIAxes4); hold(app.UIAxes4, 'on');
            plot(app.UIAxes4, t, result1.x(:,3), '-b');
            if size(result2.x,1) == nSteps; plot(app.UIAxes4, t, result2.x(:,3), '-r'); end
            title(app.UIAxes4, 'Orientation of the robots');
            xlabel(app.UIAxes4, 'Time [s]'); ylabel(app.UIAxes4, 'Orientation angle [Rad]');
            legend(app.UIAxes4, {'Robot 1','Robot 2'}); hold(app.UIAxes4, 'off');
            
            % 4. Final Trajectory Plot
            cla(app.UIAxes); hold(app.UIAxes, 'on');
            if ~isempty(result1.x); plot(app.UIAxes, result1.x(:,1), result1.x(:,2), '-b'); end
            if ~isempty(result2.x); plot(app.UIAxes, result2.x(:,1), result2.x(:,2), '-r'); end
            plot(app.UIAxes, goal1(1), goal1(2), 'ob', 'MarkerSize', 5);
            plot(app.UIAxes, goal2(1), goal2(2), 'or', 'MarkerSize', 5);
            plot(app.UIAxes, obstacle(:,1), obstacle(:,2), 'ks', 'MarkerSize', 5, 'MarkerFaceColor','k');
            xlabel(app.UIAxes, 'x [m]'); ylabel(app.UIAxes, 'y [m]');
            title(app.UIAxes, 'Trajectory of the Robots');
            axis(app.UIAxes, [-1 12 -1 12]);
            grid(app.UIAxes, 'on'); hold(app.UIAxes, 'off');
        
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            global stop_button
            stop_button = 1;
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            uiwait
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            uiresume
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Trajectory of the Robot')
            xlabel(app.UIAxes, 'x [m]')
            ylabel(app.UIAxes, 'y[m]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Velocity of the Robot')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Velocity [m/s]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Orientation  of the robot')
            xlabel(app.UIAxes4, 'Time')
            ylabel(app.UIAxes4, 'Orientation angle [Rad]')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Angular velocity of the robot')
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'Angular Velocity [rad/s]')
            zlabel(app.UIAxes3, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.InitialXSpinnerLabel = uilabel(app.UIFigure);
            app.InitialXSpinnerLabel.Text = 'Initial X';
            app.InitialXSpinner = uispinner(app.UIFigure);
            app.InitialXSpinner.Limits = [0 12];
            app.InitialYSpinnerLabel = uilabel(app.UIFigure);
            app.InitialYSpinnerLabel.Text = 'Initial Y';
            app.InitialYSpinner = uispinner(app.UIFigure);
            app.InitialYSpinner.Limits = [0 12];
            app.GoalXSpinnerLabel = uilabel(app.UIFigure);
            app.GoalXSpinnerLabel.Text = 'Goal X';
            app.GoalXSpinner = uispinner(app.UIFigure);
            app.GoalXSpinner.Limits = [0 12];
            app.GoalXSpinner.Value = 5;
            app.GoalYSpinnerLabel = uilabel(app.UIFigure);
            app.GoalYSpinnerLabel.Text = 'Goal Y';
            app.GoalYSpinner = uispinner(app.UIFigure);
            app.GoalYSpinner.Limits = [0 12];
            app.GoalYSpinner.Value = 5;
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.EnterinitialandgoalpositionfortherobotLabel = uilabel(app.UIFigure);
            app.EnterinitialandgoalpositionfortherobotLabel.Text = 'Enter initial and goal position for the robot';
            app.InitialXSpinner_2Label = uilabel(app.UIFigure);
            app.InitialXSpinner_2Label.Text = 'Initial X';
            app.InitialXSpinner_2 = uispinner(app.UIFigure);
            app.InitialXSpinner_2.Limits = [0 12];
            app.InitialXSpinner_2.Value = 11;
            app.InitialYSpinner_2Label = uilabel(app.UIFigure);
            app.InitialYSpinner_2Label.Text = 'Initial Y';
            app.InitialYSpinner_2 = uispinner(app.UIFigure);
            app.InitialYSpinner_2.Limits = [0 12];
            app.InitialYSpinner_2.Value = 11;
            app.GoalXSpinner_2Label = uilabel(app.UIFigure);
            app.GoalXSpinner_2Label.Text = 'Goal X';
            app.GoalXSpinner_2 = uispinner(app.UIFigure);
            app.GoalXSpinner_2.Limits = [0 12];
            app.GoalXSpinner_2.Value = 6;
            app.GoalYSpinner_2Label = uilabel(app.UIFigure);
            app.GoalYSpinner_2Label.Text = 'Goal Y';
            app.GoalYSpinner_2 = uispinner(app.UIFigure);
            app.GoalYSpinner_2.Limits = [0 12];
            app.GoalYSpinner_2.Value = 6;
            app.Robot1Label = uilabel(app.UIFigure);
            app.Robot1Label.Text = 'Robot 1';
            app.Robot2Label = uilabel(app.UIFigure);
            app.Robot2Label.Text = 'Robot 2';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'DWA', 'A*', 'RRT*'};
            app.DropDown.Value = 'DWA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = run

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end